classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        detecttumorButton  matlab.ui.control.Button
        uploadimageButton  matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
        UIAxes3            matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: uploadimageButton
        function uploadimageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.jpeg','image files *.jpg,*.png,*.bmp,*.jpeg'});
if isequal(file, 0)
    return; % If no file selected, exit the function
end

fullFileName = fullfile(path, file);
app.img = imread(fullFileName); % Store image in app property for later use

% Display the image in the first axes
imshow(app.img, 'Parent', app.UIAxes);
title(app.UIAxes, 'Uploaded Image');
            
        end

        % Button pushed function: detecttumorButton
        function detecttumorButtonPushed(app, event)
            if isempty(app.img)
    uialert(app.UIFigure, 'Please upload an image first.', 'No Image');
    return;
end

grayImg = im2gray(app.img);
grayImg = im2double(grayImg);
threshold = graythresh(grayImg);
bwImage = imbinarize(grayImg, threshold);

% Apply K-means Clustering
L = imsegkmeans(single(grayImg), 2); % Clustering into 2 groups

% Choose cluster with higher intensity
tumorMask = L == 2;

% Remove small objects
tumorMask = bwareaopen(tumorMask, 50);

% Display result in second axes
imshow(tumorMask, 'Parent', app.UIAxes2);
title(app.UIAxes2, 'Detected Tumor Region');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'original image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'tumor mask')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'overlay image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.uploadimageButton = uibutton(app.UIFigure, 'push');
            app.uploadimageButton.ButtonPushedFcn = createCallbackFcn(app, @uploadimageButtonPushed, true);
            app.uploadimageButton.Text = 'upload image';
            app.detecttumorButton = uibutton(app.UIFigure, 'push');
            app.detecttumorButton.ButtonPushedFcn = createCallbackFcn(app, @detecttumorButtonPushed, true);
            app.detecttumorButton.Text = 'detect tumor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end